classdef streaming < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Switch                 matlab.ui.control.Switch
        PORTDropDownLabel      matlab.ui.control.Label
        PORTDropDown           matlab.ui.control.DropDown
        tatsPanel              matlab.ui.container.Panel
        EnvolLampLabel         matlab.ui.control.Label
        EnvolLamp              matlab.ui.control.Lamp
        PriodedapogeLampLabel  matlab.ui.control.Label
        PriodedapogeLamp       matlab.ui.control.Lamp
        ParachuteLampLabel     matlab.ui.control.Label
        ParachuteLamp          matlab.ui.control.Lamp
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        COM;
        data;
        arduinoObj;
        n;
        R = 1;
        C=0.0005;
                
        z1 = linspace( 0,0, 100);
                                
        x2 = linspace( 0,0, 100);
                
        y3 = linspace( 0,0, 100);
        
    end
    
    methods (Access = public)
        
        function read(app,src, ~)
                table = 'tpsgzhyjxc';
                % Read the ASCII data from the serialport object.
                
                while src.NumBytesAvailable ~= 0 || app.data == "\n" || strlength(app.data) < 44
                   app.data = readline(src);
                   
                   if strlength(app.data) > 44
                       break;
                   end
                end


                src.UserData.Count = src.UserData.Count + 1;
                
                
                zer = app.data;
                expression = '[a-z][-]?[\d]+';
                matchStr = regexp(zer,expression,'match');
    
                m = max(size(matchStr));
                u = zeros(length(table),1);
    
                for i = 1:m 
        
        
                     for j = 1:length(table)
                        
                        if matchStr{i}(1) == table(j)
                
                         u(j) = str2double(matchStr{i}(2:end));
                
                        end
            
                     end    

                 end

    
                if u(2) ~= 0
                    u(2) = u(2) + 100000;
                end

                
                h = 44330.8*(1-(u(2)/101325)^0.190289);
                
                if src.UserData.Count == 1
                app.n = animatedline(app.UIAxes,(1),h,'Color','r');
                xlim(app.UIAxes,[0 30]);
                ylim(app.UIAxes,[-50 400]);
                
                end
                
                if u(1) > 30000
                   xlim(app.UIAxes,[u(1)/1000-30 u(1)/1000])
                end
                
                if isnan(u(1)) || u(1) == 0 || isnan(u(2)) || u(1) == 0
                
                else
                    addpoints(app.n,u(1)/1000,h);
                end             
                
                drawnow;
                
                    
                if abs(u(9)/2077) > 2 || abs(u(7)/2044) > 2 || abs(u(5)/2043) > 2
                    accelmax = max([abs(u(9)/2077),abs(u(7)/2044),abs(u(5)/2043)]);
                    xlim(app.UIAxes2,[-(accelmax) (accelmax)]);
                    ylim(app.UIAxes2,[-(accelmax) (accelmax)]);
                    zlim(app.UIAxes2,[-(accelmax) (accelmax)]);
                end
                
                quiver3(app.UIAxes2,0,0,0,u(9)/2077,u(7)/2044,u(5)/2043) %%ici inverser si besoin
                
                
                if(u(8)>0)
                    th1 = linspace( 0, -(u(4)-13), 100);
                else 
                    th1 = linspace( -(u(4)-13), 0, 100);
                end
                x1 = app.R*cos(th1*app.C);
                y1 = app.R*sin(th1*app.C);
                
                if(u(6)>0)
                    th2 = linspace( 0, u(8), 100);
                else 
                    th2 = linspace( u(8), 0, 100);
                end
                z2 = app.R*cos(th2*app.C);
                y2 = app.R*sin(th2*app.C);
                
                if(u(4)>0)
                    th3 = linspace( 0, -(u(6)-5), 100);
                else 
                    th3 = linspace( -(u(6)-5), 0, 100);
                end
                z3 = app.R*cos(th3*app.C);
                x3 = app.R*sin(th3*app.C);
                
                
                plot3(app.UIAxes3,x1,y1,app.z1,"color",'red'); 
                hold(app.UIAxes3,"on")
                plot3(app.UIAxes3,app.x2,y2,z2,"color",'blue');                
                plot3(app.UIAxes3,x3,app.y3,z3,"color",'yellow');
                hold(app.UIAxes3,"off")
                
                if u(3) == 48
                    app.EnvolLamp.Color =  'red';
                end
                
                if u(3) == 49
                    app.EnvolLamp.Color =  'green';
                end
                if u(3) == 50
                    app.PriodedapogeLamp.Color =  'green';           
                end
                if u(3) == 51
                    app.ParachuteLamp.Color =  'green';
                end
                if u(3) == 52
                    app.ParachuteLamp.Color =  'blue';
                end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = "TARANIS flight observer";
            
            app.PORTDropDown.Items = serialportlist("available");
            app.COM = app.PORTDropDown.Value;
            app.EnvolLamp.Color =  'black';
            app.PriodedapogeLamp.Color =  'black';
            app.ParachuteLamp.Color =  'black';


            axis(app.UIAxes2,"equal");
            xlim(app.UIAxes2,[-2 2]);
            ylim(app.UIAxes2,[-2 2]);
            zlim(app.UIAxes2,[-2 2]);
            
            axis(app.UIAxes3,'equal');
            xlim(app.UIAxes3,[-1 1]);
            ylim(app.UIAxes3,[-1 1]);
            zlim(app.UIAxes3,[-1 1]);
            
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value == "On"
                app.arduinoObj = serialport(app.COM,115200);

                configureTerminator(app.arduinoObj,"CR/LF");

                flush(app.arduinoObj);
                
                app.arduinoObj.UserData = struct("Count",0);
                
                configureCallback(app.arduinoObj,"terminator",@app.read);
            end
            if app.Switch.Value == "Off"
                delete(app.arduinoObj);
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                pause(2);

                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
            end
            
        end

        % Value changed function: PORTDropDown
        function PORTDropDownValueChanged(app, event)
            app.COM = app.PORTDropDown.Value;
            
        end

        % Drop down opening function: PORTDropDown
        function PORTDropDownOpening(app, event)
            app.PORTDropDown.Items = serialportlist("available");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.PORTDropDownLabel = uilabel(app.UIFigure);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.UIFigure);
            app.PORTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PORTDropDownOpening, true);
            app.PORTDropDown.ValueChangedFcn = createCallbackFcn(app, @PORTDropDownValueChanged, true);
            app.tatsPanel = uipanel(app.UIFigure);
            app.tatsPanel.Title = 'États';
            app.EnvolLampLabel = uilabel(app.tatsPanel);
            app.EnvolLampLabel.Text = 'En vol';
            app.EnvolLamp = uilamp(app.tatsPanel);
            app.PriodedapogeLampLabel = uilabel(app.tatsPanel);
            app.PriodedapogeLampLabel.Text = 'Période d''apogée';
            app.PriodedapogeLamp = uilamp(app.tatsPanel);
            app.ParachuteLampLabel = uilabel(app.tatsPanel);
            app.ParachuteLampLabel.Text = 'Parachute';
            app.ParachuteLamp = uilamp(app.tatsPanel);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Altitude')
            xlabel(app.UIAxes, 'Temps (s)')
            ylabel(app.UIAxes, 'hauteur (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Accélération')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Rotation')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = streaming

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end